classdef ImageEncrypter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterImagepathLabel             matlab.ui.control.Label
        EnterImagepathEditField         matlab.ui.control.EditField
        EnterDestinationfolderpathLabel  matlab.ui.control.Label
        EnterDestinationfolderpathEditField  matlab.ui.control.EditField
        EntermatfilepathEditFieldLabel  matlab.ui.control.Label
        EntermatfilepathEditField       matlab.ui.control.EditField
        EncryptButton                   matlab.ui.control.Button
        DecryptButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        KeyLabel                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            key=imread('key.jpg');
            imshow(key,'parent', app.UIAxes);
            app.KeyLabel.Text='Key';
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            imagedir=app.EnterImagepathEditField.Value;
            image=imread(imagedir);
            image=im2double(image);
            layer1=image(:,:,1);
            layer2=image(:,:,2);
            layer3=image(:,:,3);
            key=imread('key.jpg');
            key=im2double(key);
            key1=key(:,:,1);
            key2=key(:,:,2);
            key3=key(:,:,3);
            layer1=key1*layer1;
            layer2=key2*layer2;
            layer3=key3*layer3;
            final(:,:,1)=layer1(:,:,1);
            final(:,:,2)=layer2(:,:,1);
            final(:,:,3)=layer3(:,:,1);
            final=final/100;
            imshow(final,'parent', app.UIAxes);
            DestinationDir=app.EnterDestinationfolderpathEditField.Value;
            imwrite(final,strcat(DestinationDir,'EncrytedImage.jpg'));
            save(strcat(DestinationDir,'Encrypted.mat'),'final');
            app.KeyLabel.Text='Encrypted Image';
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
        imagedir=app.EnterImagepathEditField.Value;
        imshow(imagedir,'parent', app.UIAxes);
        app.KeyLabel.Text='Image processing..';
        pause(5);
        matdir=app.EntermatfilepathEditField.Value;
        load(matdir);
        image=final;
        image=image*100;
        layer1=image(:,:,1);
        layer2=image(:,:,2);
        layer3=image(:,:,3);
        key=imread('key.jpg');
        key=im2double(key);
        key1=key(:,:,1);
        key2=key(:,:,2);
        key3=key(:,:,3);
        layer1=inv(key1)*layer1;
        layer2=inv(key2)*layer2;
        layer3=inv(key3)*layer3;
        final(:,:,1)=layer1(:,:,1);
        final(:,:,2)=layer2(:,:,1);
        final(:,:,3)=layer3(:,:,1);
        imshow(final,'parent', app.UIAxes);
        DestinationDir=app.EnterDestinationfolderpathEditField.Value;
        imwrite(final,strcat(DestinationDir,'DecryptedImage.jpg'));
        app.KeyLabel.Text='Decrypted Image';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnterImagepathLabel = uilabel(app.UIFigure);
            app.EnterImagepathLabel.Text = 'Enter Image path:';
            app.EnterImagepathEditField = uieditfield(app.UIFigure, 'text');
            app.EnterDestinationfolderpathLabel = uilabel(app.UIFigure);
            app.EnterDestinationfolderpathLabel.Text = 'Enter Destination folder path:';
            app.EnterDestinationfolderpathEditField = uieditfield(app.UIFigure, 'text');
            app.EntermatfilepathEditFieldLabel = uilabel(app.UIFigure);
            app.EntermatfilepathEditFieldLabel.Text = 'Enter matfile path:';
            app.EntermatfilepathEditField = uieditfield(app.UIFigure, 'text');
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.DecryptButton = uibutton(app.UIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.UIAxes = uiaxes(app.UIFigure);
            app.KeyLabel = uilabel(app.UIFigure);
            app.KeyLabel.Text = 'Key';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageEncrypter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end